classdef rozvrhfinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Switch                       matlab.ui.control.Switch
        MTSButton                    matlab.ui.control.Button
        MTSP                         matlab.ui.control.Button
        AUTP                         matlab.ui.control.Button
        PTBS                         matlab.ui.control.Button
        MTSButton_ut                 matlab.ui.control.Button
        AUTP_2                       matlab.ui.control.Button
        EPEP                         matlab.ui.control.Button
        EPES                         matlab.ui.control.Button
        MASC                         matlab.ui.control.Button
        PTBC                         matlab.ui.control.Button
        PSP                          matlab.ui.control.Button
        EPEC                         matlab.ui.control.Button
        PTBP                         matlab.ui.control.Button
        PO                           matlab.ui.control.Button
        UT                           matlab.ui.control.Button
        ST                           matlab.ui.control.Button
        CT                           matlab.ui.control.Button
        PA                           matlab.ui.control.Button
        Button                       matlab.ui.control.Button
        Button_2                     matlab.ui.control.Button
        Button_3                     matlab.ui.control.Button
        Button_4                     matlab.ui.control.Button
        Button_5                     matlab.ui.control.Button
        Button_6                     matlab.ui.control.Button
        Button_7                     matlab.ui.control.Button
        Button_8                     matlab.ui.control.Button
        Button_9                     matlab.ui.control.Button
        Button_10                    matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        Jmeno                        matlab.ui.control.Button
        XButton                      matlab.ui.control.Button
        VyucujeLabel                 matlab.ui.control.Label
        TeamsLabel                   matlab.ui.control.Label
        Teamslink                    matlab.ui.control.Button
        PoznamkyLabel                matlab.ui.control.Label
        Poznamkybox                  matlab.ui.control.TextArea
        UlozButton                   matlab.ui.control.Button
        location                     matlab.ui.control.Button
        AktualniTydenEditFieldLabel  matlab.ui.control.Label
        AktualniTyden                matlab.ui.control.EditField
        Label                        matlab.ui.control.Label
        PraveprobihaLabel            matlab.ui.control.Label
        Button_11                    matlab.ui.control.Button
        Button_12                    matlab.ui.control.Button
    end


    methods (Access = private)
    
        function  redu1(app,jmeno)
            if isfile(jmeno)     
                fil=dir(jmeno);
                name=fil.name;
                folder=fil.folder;
                path=strcat(folder,{'\'},name);
                app.Poznamkybox.Value=importdata(name);
            else
                data=fopen(jmeno,'w');
                fprintf(data,'Zde pis poznamky: ');
                fclose(data);
            end
        end
        
        function redu2(app,value)
            switch value
                case 'MTS'
                app.Jmeno.Text = 'Ing. Jiri Zatopek';
                app.Panel.Title = 'MTS';
                app.location.Visible='on';
                if isfile('mts2021poznamky.txt')
                fil=dir('mts2021poznamky.txt');
                name=fil.name;
                folder=fil.folder;
                path=strcat(folder,{'\'},name);
                app.Poznamkybox.Value=importdata(name);
                
            else
                data=fopen('mts2021poznamky.txt','w');
                fprintf(data,'Zde pis poznamky: ');
                fclose(data);
                end
                case 'AUT'
                app.Jmeno.Text = 'doc. Ing. Libor Pekar, Ph.D.';
                app.Panel.Title = 'AUT';
                app.location.Visible='off';
            if isfile('aut2021poznamky.txt')
                fil=dir('aut2021poznamky.txt');
                name=fil.name;
                folder=fil.folder;
                path=strcat(folder,{'\'},name);
                app.Poznamkybox.Value=importdata(name);
            else
                data=fopen('aut2021poznamky.txt','w');
                fprintf(data,'Zde pis poznamky: ');
                fclose(data);
            end
            end 
        end
        
        function redu3(app,nazev)
            if isfile(nazev)
                    b={};
                    data=fopen(nazev,'w');
                    fprintf(data, '%s', app.Poznamkybox.Value{:});
                    
                    fprintf(data,'\n');
                    fclose(data);
            else
                    b={};
                    data=fopen(nazev,'w');
                    data=fopen(nazev,'w');
                    fprintf(data, '%s', app.Poznamkybox.Value{:});
                    
                    fprintf(data,'\n');
                    fclose(data);
               end 
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ssudy={{'test','Wed','17'},{'MTS','Mon','09','10','11','12'},{'AUT','Mon','13','14','15'},{'PTB','Mon','15','18'},{'MTS','Tue','11','12'},{'AUT','Tue','13','14','15'},{'EPE','Tue','16','17'},{'EPE','Wed','09'},{'MAS','Wed','10','11'},{'PTB','Wed','12','13','14'},{'PS','Thu','08','09'},{'EPE','Thu','10','11'},{'PTB','Thu','12','13'}};
            slichy={{'MTS','Mon','11','12'},{'AUT','Mon','09','10','13','14','15'},{'PTB','Mon','15','18'},{'AUT','Tue','11','12','13','14','15'},{'EPE','Tue','16','17'},{'EPE','Wed','09'},{'MAS','Wed','10','11'},{'PTB','Wed','12','13','14'},{'PS','Thu','08','09'},{'EPE','Thu','10','11'},{'PTB','Thu','12','13'}};
            c=today;
            a=weeknum(c)-1;
            
            if mod(a,2)==0
                app.AktualniTyden.Value='Sudy';
                choice=ssudy;
            else
                app.AktualniTyden.Value='Lichy';
                choice=slichy;
            end
            
            global unl;
            unl=0;
            global h;%aktualni hodina
            global s;%aktualni den v tydnu
           
            while unl==0
            for j=1:1
                %try
                t = datestr(datetime('now','TimeZone','local','Format','d-MMM-y HH:mm:ss Z'));
                app.Label.Text=t;
               %catch
               %end
                pause(1);
                
            end
            
            for i=1:1
            h=datestr(now,'HH');
            [n,s]=weekday(t);
            sc=(size(choice));
            for i = 1:sc(2)
               sce=(size(choice{i}));
               for j = 1:sce(2)
                   
                   containsday = any(strcmp(choice{i}, s));
                   containshour = any(strcmp(choice{i}, h ));
                   if containsday==1 && containshour==containsday
                       p=choice{i}{1};
                       try%%%
                       
                       app.Button_12.Text=p;
                       catch%%%
                       end%%%
                   end
               end
            end%for
            %app.Button_12.Text=p;
            %pause(5);
            end
            
          
            end
           
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
         
            value = app.Switch.Value;
            switch value
                case 'Sudy'
                     app.MTSButton.Text = 'MTS';
                     app.MTSButton_ut.Text = 'MTS';
                     
                case 'Lichy'
                    app.MTSButton.Text = 'AUT';
                    app.MTSButton_ut.Text = 'AUT';
            end   
        end

        % Button pushed function: MTSButton
        function MTSButtonPushed(app, event)
            app.Panel.Visible = 'on';
            value= app.MTSButton.Text;
            redu2(app,value);
        end

        % Size changed function: Panel
        function PanelSizeChanged(app, event)
            position = app.Panel.Position;
            
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            app.Panel.Visible = 'off';
            predmety=["mts" "aut" "ptb" "epe" "ps" "mas"];
            b='2021poznamky.txt';
            pat=0;
            for i = 1:6
                s=strcat(predmety(i),b);    
                if isfile(s)
                    f=fileread(s);
                    pat=strfind(f,'Zde pis poznamky: ');
                    
                end
                if pat==1
                    delete(s);
                end
                
            end
            app.Poznamkybox.Value='Zde pis poznamky: ';
            
        end

        % Button pushed function: MTSP
        function MTSPButtonPushed(app, event)
            app.Panel.Visible = 'on';
            app.location.Visible='on';
            app.Jmeno.Text = 'doc. RNDr.Ing. Zdenek Urednicek, CSc.';
            app.Panel.Title = 'MTS';
            jmeno='mts2021poznamky.txt';
            redu1(app,jmeno);  
        end

        % Button pushed function: AUTP
        function AUTPButtonPushed(app, event)
            app.Panel.Visible = 'on';
            app.location.Visible='off';
            app.Jmeno.Text = 'prof. Ing. Vladimir Vasek, CSc.';
            app.Panel.Title = 'AUT';
            jmeno='aut2021poznamky.txt';
            redu1(app,jmeno);
            
        end

        % Button pushed function: PTBS
        function PTBSButtonPushed(app, event)
            app.Panel.Visible = 'on';
            app.Jmeno.Text = 'prof. Ing. Karel Kolomaznik, DrSc.';
            app.Panel.Title = 'PTB';
            jmeno='ptb2021poznamky.txt';
            redu1(app,jmeno);
            
        end

        % Button pushed function: MTSButton_ut
        function MTSButton_utPushed(app, event)
            app.Panel.Visible = 'on';
            value= app.MTSButton.Text;
            redu2(app,value);
        end

        % Button pushed function: AUTP_2
        function AUTP_2ButtonPushed(app, event)
            app.Panel.Visible = 'on';
            app.location.Visible='off';
            app.Jmeno.Text = 'prof. Ing. Vladimir Vasek, CSc.';
            app.Panel.Title = 'AUT';
            jmeno='aut2021poznamky.txt';
            redu1(app,jmeno);
        end

        % Button pushed function: EPEP
        function EPEPButtonPushed(app, event)
            app.Panel.Visible = 'on';
            app.Jmeno.Text = 'doc. Mgr. Milan Adamek, Ph.D.';
            app.Panel.Title = 'EPE';
            jmeno='epe2021poznamky.txt';
            redu1(app,jmeno);
        end

        % Button pushed function: EPES
        function EPESButtonPushed(app, event)
            app.Panel.Visible = 'on';
            app.Jmeno.Text = 'Ing. Petr Dostalek, Ph.D.';
            app.Panel.Title = 'EPE';
            jmeno='epe2021poznamky.txt';
            redu1(app,jmeno);
        end

        % Button pushed function: MASC
        function MASCButtonPushed(app, event)
            app.Panel.Visible = 'on';
            app.Jmeno.Text = 'doc. Ing. Frantisek Gazdos, Ph.D.';
            app.Panel.Title = 'MAS';
            jmeno='mas2021poznamky.txt';
            redu1(app,jmeno);
        end

        % Button pushed function: PTBC
        function PTBCButtonPushed(app, event)
            app.Panel.Visible = 'on';
            app.Jmeno.Text = 'doc. Ing. Alena Kalendova, Ph.D.';
            app.Panel.Title = 'PTB';
            jmeno='ptb2021poznamky.txt';
            redu1(app,jmeno);
        end

        % Button pushed function: PSP
        function PSPButtonPushed(app, event)
              app.Panel.Visible = 'on';
              app.location.Visible='off';
              app.Jmeno.Text = 'Ing. Miroslav Matysek, Ph.D.';
              app.Panel.Title = 'PS';
              jmeno='ps2021poznamky.txt';
              redu1(app,jmeno);
        end

        % Button pushed function: EPEC
        function EPECButtonPushed(app, event)
            app.Panel.Visible = 'on';
            app.Jmeno.Text = 'Ing. Karel Perutka, Ph.D.';
            app.Panel.Title = 'EPE';
            jmeno='epe2021poznamky.txt';
            redu1(app,jmeno);
        end

        % Button pushed function: PTBP
        function PTBPButtonPushed(app, event)
            app.Panel.Visible = 'on';
            app.Jmeno.Text = 'prof. Ing. Dagmar Janacova, CSc.';
            app.Panel.Title = 'PTB';
            jmeno='ptb2021poznamky.txt';
            redu1(app,jmeno);
        end

        % Button pushed function: Jmeno
        function JmenoButtonPushed(app, event)
            value=app.Jmeno.Text;
            switch value
                case 'prof. Ing. Dagmar Janacova, CSc.'
                    web('https://fai.utb.cz/contacts/prof-ing-dagmar-janacova-csc/','-browser');
                case 'Ing. Jiri Zatopek'
                    web('https://fai.utb.cz/contacts/ing-jiri-zatopek/','-browser');
                case 'doc. RNDr.Ing. Zdenek Urednicek, CSc.'
                    web('https://fai.utb.cz/contacts/doc-rndr-ing-zdenek-urednicek-csc/','-browser');
                case 'doc. Mgr. Milan Adamek, Ph.D.'
                    web('https://fai.utb.cz/contacts/doc-mgr-milan-adamek-ph-d/','-browser');
                case 'Ing. Petr Dostalek, Ph.D.'
                    web('https://fai.utb.cz/contacts/ing-petr-dostalek-ph-d/','-browser');
                case 'doc. Ing. Frantisek Gazdos, Ph.D.'
                    web('https://fai.utb.cz/contacts/doc-ing-frantisek-gazdos-ph-d/','-browser');
                case 'doc. Ing. Alena Kalendova, Ph.D.'
                    web('https://ft.utb.cz/contacts/ing-alena-kalendova-ph-d/','-browser');
                case 'Ing. Miroslav Matysek, Ph.D.'
                    web('https://fai.utb.cz/contacts/ing-miroslav-matysek-ph-d/','-browser');
                case 'prof. Ing. Vladimir Vasek, CSc.'
                    web('https://fai.utb.cz/contacts/prof-ing-vladimir-vasek-csc/','-browser');
                case 'Ing. Karel Perutka, Ph.D.'
                    web('https://fai.utb.cz/contacts/ing-karel-perutka-ph-d/','-browser');
                case 'prof. Ing. Karel Kolomaznik, DrSc.'
                    web('https://fai.utb.cz/contacts/prof-ing-karel-kolomaznik-drsc/','-browser');
                case 'doc. Ing. Libor Pekar, Ph.D.'
                    web('https://fai.utb.cz/contacts/doc-ing-libor-pekar-ph-d/','-browser');
            end
        end

        % Button pushed function: Teamslink
        function TeamslinkButtonPushed(app, event)
            value=app.Jmeno.Text;
            switch value
                case 'prof. Ing. Dagmar Janacova, CSc.'
                    web('https://teams.microsoft.com/l/channel/19%3acc353d6672c341938d31de7fa243314c%40thread.tacv2/Obecn%25C3%25A9?groupId=4253e590-cf29-4070-a41a-aa1529593cb6&tenantId=03e3d621-f48e-467f-8a5a-6b74a23db6c7','-browser');
                case 'Ing. Jiri Zatopek'
                    web('https://teams.microsoft.com/l/channel/19%3a31f78164f9584781898c36ce2c226eb5%40thread.tacv2/Obecn%25C3%25A9?groupId=d9df0b10-8807-435a-bfce-4065647c5783&tenantId=03e3d621-f48e-467f-8a5a-6b74a23db6c7','-browser');
                case 'doc. RNDr.Ing. Zdenek Urednicek, CSc.'
                    web('https://teams.microsoft.com/l/channel/19%3a31f78164f9584781898c36ce2c226eb5%40thread.tacv2/Obecn%25C3%25A9?groupId=d9df0b10-8807-435a-bfce-4065647c5783&tenantId=03e3d621-f48e-467f-8a5a-6b74a23db6c7','-browser');
                case 'doc. Mgr. Milan Adamek, Ph.D.'
                    web('https://teams.microsoft.com/l/channel/19%3a949f1b38b6b1411693e884e9f93bb6d1%40thread.tacv2/P%25C5%2599edn%25C3%25A1%25C5%25A1ky?groupId=46ec9c58-500e-4c91-80a8-83ff5de90e23&tenantId=03e3d621-f48e-467f-8a5a-6b74a23db6c7','-browser');
                case 'Ing. Petr Dostalek, Ph.D.'
                    web('https://teams.microsoft.com/l/channel/19%3ac9e1d92595c846fcad83e3feef972c0d%40thread.tacv2/Semin%25C3%25A1%25C5%2599e?groupId=46ec9c58-500e-4c91-80a8-83ff5de90e23&tenantId=03e3d621-f48e-467f-8a5a-6b74a23db6c7','-browser');
                case 'doc. Ing. Frantisek Gazdos, Ph.D.'
                    web('https://teams.microsoft.com/l/channel/19%3a6a72864eb44f4dbd8043725e5e967891%40thread.tacv2/Obecn%25C3%25A9?groupId=5e908abf-ad96-48d8-b569-20936ada7fee&tenantId=03e3d621-f48e-467f-8a5a-6b74a23db6c7','-browser');
                case 'doc. Ing. Alena Kalendova, Ph.D.'
                    web('https://ft.utb.cz/contacts/ing-alena-kalendova-ph-d/','-browser');
                case 'Ing. Miroslav Matysek, Ph.D.'
                    web('https://teams.microsoft.com/l/channel/19%3a86cf25dbf71f4dbc92034724d5990e04%40thread.tacv2/Obecn%25C3%25A9?groupId=7d0461ab-5271-4519-af39-1e9a842310e2&tenantId=03e3d621-f48e-467f-8a5a-6b74a23db6c7','-browser');
                case 'prof. Ing. Vladimir Vasek, CSc.'
                    web('https://teams.microsoft.com/l/channel/19%3a127e42c5934e410494d610464cc59b62%40thread.tacv2/P%25C5%2599edn%25C3%25A1%25C5%25A1ky%2520prof.%2520Va%25C5%25A1ka?groupId=95c6e1f2-4a0a-416e-a030-e899508d0a5f&tenantId=03e3d621-f48e-467f-8a5a-6b74a23db6c7','-browser');
                case 'Ing. Karel Perutka, Ph.D.'
                    web('https://teams.microsoft.com/l/channel/19%3a790cd4d229a140f1bb99dd3ef2327c47%40thread.tacv2/cvi%25C4%258Den%25C3%25AD%2520%25C4%258Dtvrtek%252010-12%2520Per%25C5%25AFtka?groupId=71342048-8931-4ed2-bdda-2034f1845f1d&tenantId=03e3d621-f48e-467f-8a5a-6b74a23db6c7','-browser');
                case 'prof. Ing. Karel Kolomaznik, DrSc.'
                    web('https://teams.microsoft.com/l/channel/19%3a3f4927740eb64d38894e12f133a7492b%40thread.tacv2/prof.%2520Kolomazn%25C3%25ADk?groupId=4253e590-cf29-4070-a41a-aa1529593cb6&tenantId=03e3d621-f48e-467f-8a5a-6b74a23db6c7','-browser');
                case 'doc. Ing. Libor Pekar, Ph.D.'
                    web('https://teams.microsoft.com/l/channel/19%3a791a3731a2f1445b832bb4e63dfb2486%40thread.tacv2/A4AUT%2520-%2520semin%25C3%25A1%25C5%2599?groupId=e4b1968e-284e-4170-9ed9-6227f6db5505&tenantId=03e3d621-f48e-467f-8a5a-6b74a23db6c7','-browser');
            end
        end

        % Button pushed function: UlozButton
        function UlozButtonPushed(app, event)
        path = uigetdir('');
        value = app.Panel.Title;
        switch value
            case 'MTS'
               nazev='mts2021poznamky.txt';
               redu3(app,nazev);
            
             case 'AUT'
               nazev='aut2021poznamky.txt';
               redu3(app,nazev);

             case 'PTB'
               nazev='ptb2021poznamky.txt';
               redu3(app,nazev);

             case 'EPE'
               nazev='epe2021poznamky.txt';
               redu3(app,nazev);

             case 'MAS'
               nazev='mas2021poznamky.txt';
               redu3(app,nazev);

              case 'PS'
               nazev='ps2021poznamky.txt';
               redu3(app,nazev);
        end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            global unl;
            unl=1;
            delete(app)
        end

        % Button pushed function: location
        function locationButtonPushed(app, event)
            predmet=app.Panel.Title;
            switch predmet
                case 'MTS'
                    winopen('C:\Users\XPS-\Desktop\dynast\protokoly');
                case 'AUT'
                case 'PTB'
                    winopen('C:\Users\XPS-\Desktop\ptb_protokoly');
                case 'EPE'
                    winopen('C:\Users\XPS-\Desktop\epe');
                case 'MAS'
                    winopen('C:\Users\XPS-\Documents\MATLAB');
                case 'PS'
            end
 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Sudy', 'Lichy'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Sudy';
            app.MTSButton = uibutton(app.UIFigure, 'push');
            app.MTSButton.ButtonPushedFcn = createCallbackFcn(app, @MTSButtonPushed, true);
            app.MTSButton.Text = 'MTS';
            app.MTSP = uibutton(app.UIFigure, 'push');
            app.MTSP.ButtonPushedFcn = createCallbackFcn(app, @MTSPButtonPushed, true);
            app.MTSP.Text = 'MTS';
            app.AUTP = uibutton(app.UIFigure, 'push');
            app.AUTP.ButtonPushedFcn = createCallbackFcn(app, @AUTPButtonPushed, true);
            app.AUTP.Text = 'AUT';
            app.PTBS = uibutton(app.UIFigure, 'push');
            app.PTBS.ButtonPushedFcn = createCallbackFcn(app, @PTBSButtonPushed, true);
            app.PTBS.Text = 'PTB';
            app.MTSButton_ut = uibutton(app.UIFigure, 'push');
            app.MTSButton_ut.ButtonPushedFcn = createCallbackFcn(app, @MTSButton_utPushed, true);
            app.MTSButton_ut.Text = 'MTS';
            app.AUTP_2 = uibutton(app.UIFigure, 'push');
            app.AUTP_2.ButtonPushedFcn = createCallbackFcn(app, @AUTP_2ButtonPushed, true);
            app.AUTP_2.Text = 'AUT';
            app.EPEP = uibutton(app.UIFigure, 'push');
            app.EPEP.ButtonPushedFcn = createCallbackFcn(app, @EPEPButtonPushed, true);
            app.EPEP.Text = 'EPE';
            app.EPES = uibutton(app.UIFigure, 'push');
            app.EPES.ButtonPushedFcn = createCallbackFcn(app, @EPESButtonPushed, true);
            app.EPES.Text = 'EPE';
            app.MASC = uibutton(app.UIFigure, 'push');
            app.MASC.ButtonPushedFcn = createCallbackFcn(app, @MASCButtonPushed, true);
            app.MASC.Text = 'MAS';
            app.PTBC = uibutton(app.UIFigure, 'push');
            app.PTBC.ButtonPushedFcn = createCallbackFcn(app, @PTBCButtonPushed, true);
            app.PTBC.Text = 'PTB';
            app.PSP = uibutton(app.UIFigure, 'push');
            app.PSP.ButtonPushedFcn = createCallbackFcn(app, @PSPButtonPushed, true);
            app.PSP.Text = 'PS';
            app.EPEC = uibutton(app.UIFigure, 'push');
            app.EPEC.ButtonPushedFcn = createCallbackFcn(app, @EPECButtonPushed, true);
            app.EPEC.Text = 'EPE';
            app.PTBP = uibutton(app.UIFigure, 'push');
            app.PTBP.ButtonPushedFcn = createCallbackFcn(app, @PTBPButtonPushed, true);
            app.PTBP.Text = 'PTB';
            app.PO = uibutton(app.UIFigure, 'push');
            app.PO.Text = 'PO';
            app.UT = uibutton(app.UIFigure, 'push');
            app.UT.Text = 'UT';
            app.ST = uibutton(app.UIFigure, 'push');
            app.ST.Text = 'ST';
            app.CT = uibutton(app.UIFigure, 'push');
            app.CT.Text = 'CT';
            app.PA = uibutton(app.UIFigure, 'push');
            app.PA.Text = 'PA';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Text = '7-8';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Text = '8-9';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.Text = '9-10';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.Text = '10-11';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.Text = '11-12';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.Text = '12-13';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.Text = '13-14';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.Text = '14-15';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.Text = '15-16';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.Text = '17-18';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Panel.Visible = 'off';
            app.Panel.SizeChangedFcn = createCallbackFcn(app, @PanelSizeChanged, true);
            app.Jmeno = uibutton(app.Panel, 'push');
            app.Jmeno.ButtonPushedFcn = createCallbackFcn(app, @JmenoButtonPushed, true);
            app.XButton = uibutton(app.Panel, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X';
            app.VyucujeLabel = uilabel(app.Panel);
            app.VyucujeLabel.Text = 'Vyucuje:';
            app.TeamsLabel = uilabel(app.Panel);
            app.TeamsLabel.Text = 'Teams:';
            app.Teamslink = uibutton(app.Panel, 'push');
            app.Teamslink.ButtonPushedFcn = createCallbackFcn(app, @TeamslinkButtonPushed, true);
            app.Teamslink.Text = 'Pripojit';
            app.PoznamkyLabel = uilabel(app.Panel);
            app.PoznamkyLabel.Text = 'Poznamky:';
            app.Poznamkybox = uitextarea(app.Panel);
            app.UlozButton = uibutton(app.Panel, 'push');
            app.UlozButton.ButtonPushedFcn = createCallbackFcn(app, @UlozButtonPushed, true);
            app.UlozButton.Text = 'Uloz';
            app.location = uibutton(app.Panel, 'push');
            app.location.ButtonPushedFcn = createCallbackFcn(app, @locationButtonPushed, true);
            app.location.Text = '...';
            app.AktualniTydenEditFieldLabel = uilabel(app.UIFigure);
            app.AktualniTydenEditFieldLabel.Text = 'Aktualni Tyden';
            app.AktualniTyden = uieditfield(app.UIFigure, 'text');
            app.AktualniTyden.Editable = 'off';
            app.Label = uilabel(app.UIFigure);
            app.PraveprobihaLabel = uilabel(app.UIFigure);
            app.PraveprobihaLabel.Text = 'Prave probiha:';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.Text = '16-17';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = rozvrhfinal

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end